classdef DemoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        StartCameraButton       matlab.ui.control.Button
        TakePhotoButton         matlab.ui.control.Button
        RetakePhotoButton       matlab.ui.control.Button
        UsePhotoButton          matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
        AddSensitiveAreaButton  matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        DoShareButton           matlab.ui.control.Button
        ShareLevelSliderLabel   matlab.ui.control.Label
        ShareLevelSlider        matlab.ui.control.Slider
        Spinner                 matlab.ui.control.Spinner
        DebugDataLabel          matlab.ui.control.Label
        UseSampleImageButton    matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        XButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        cam         % camera
        camsize     % camera size
        im          % UI image of camera
        img         % taken photo
        outImg      % shared img
        area        % current highlighted area
        QT          % Quantization table used
        Z           % Zigzag order
        S           % Sensitive area information
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.QT = [8 6 4 4 3 3 2 2;
                      6 4 4 3 3 2 2 1;
                      4 4 3 3 2 2 1 1;
                      4 3 3 2 2 1 1 1;
                      3 3 2 2 1 1 1 1;
                      3 2 2 1 1 1 1 1;
                      2 2 1 1 1 1 1 1;
                      2 1 1 1 1 1 1 1;];
            app.Z = [ 1  2  9 17 10  3  4 11 ...
                     18 25 33 26 19 12  5  6 ...
                     13 20 27 34 41 49 42 35 ...
                     28 21 14  7  8 15 22 29 ...
                     36 43 50 57 58 51 44 37 ...
                     30 23 16 24 31 38 45 52 ...
                     59 60 53 46 39 32 40 47 ...
                     54 61 62 55 48 56 63 64];
            
            app.cam = webcam;
            app.camsize = str2double( strsplit(app.cam.Resolution, 'x'));
            app.DebugDataLabel.Text = 'Choose Camera or Use Image';
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            % Initial Everytime
            set(app.ShareLevelSlider,'Enable','off');
            for i = 2:12
                app.S(i).s = [];
            end
            
            app.im = image(zeros([app.camsize 3]),'Parent',app.UIAxes);
            preview(app.cam, app.im);
            
            set(app.StartCameraButton,'Enable','off');
            set(app.UseSampleImageButton,'Enable','off');
            set(app.ListBox,'Enable','off');
            set(app.TakePhotoButton,'Enable','on');
            app.DebugDataLabel.Text = 'Click to take photo';
        end

        % Button pushed function: TakePhotoButton
        function TakePhotoButtonPushed(app, event)
            closePreview(app.cam);
            
            app.img = snapshot(app.cam);
            app.img = rgb2gray(app.img);
            imshow(app.img, 'Parent', app.UIAxes);
            
            set(app.TakePhotoButton,'Enable','off');
            set(app.RetakePhotoButton,'Enable','on');
            set(app.UsePhotoButton,'Enable','on');
            app.DebugDataLabel.Text = 'Accept or Retake';
        end

        % Button pushed function: RetakePhotoButton
        function RetakePhotoButtonPushed(app, event)
            app.im = image(zeros(1280, 720, 3),'Parent',app.UIAxes);
            preview(app.cam,app.im);
            
            set(app.TakePhotoButton,'Enable','on');
            set(app.RetakePhotoButton,'Enable','off');
            set(app.UsePhotoButton,'Enable','off');
            app.DebugDataLabel.Text = 'Click to take photo';
        end

        % Button pushed function: UsePhotoButton
        function UsePhotoButtonPushed(app, event)
            app.img = imresize(app.img, 0.5);
            imshow(app.img, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');

            set(app.RetakePhotoButton,'Enable','off');
            set(app.UsePhotoButton,'Enable','off');
            set(app.Spinner,'Enable','on');
            set(app.AddSensitiveAreaButton,'Enable','on');
            set(app.DoShareButton,'Enable','on');
            app.DebugDataLabel.Text = 'Add sensitive area or Do share';
        end

        % Button pushed function: AddSensitiveAreaButton
        function AddSensitiveAreaButtonPushed(app, event)
            set(app.Spinner,'Enable','off');
            set(app.AddSensitiveAreaButton,'Enable','off');
            set(app.DoShareButton,'Enable','off');
            
            imshow(app.img,'InitialMagnification',400);
%             [x, y] = ginput(2);
            % Choose by drag
            k = waitforbuttonpress;
            p1 = get(gca,'CurrentPoint');   % button down detected
            rbbox;                          % return figure units
            p2 = get(gca,'CurrentPoint');   % button up detected
            p1 = p1(1,1:2);                 % extract x and y
            p2 = p2(1,1:2);
            
            mx = round(min(p1(1),p2(1))/8)*8;
            Mx = round(max(p1(1),p2(1))/8)*8;
            my = floor((min(p1(2),p2(2))+4)/16)*16+8;
            My = ceil((max(p1(2),p2(2))-4)/16)*16;
            close
            
            % Work around for window focus
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.area = fill(app.UIAxes, [mx;Mx;Mx;mx],[my;my;My;My],'y','facealpha',0.5);
            
            set(app.OKButton,'Enable','on');
            set(app.CancelButton,'Enable','on');
            app.DebugDataLabel.Text = 'Use this area?';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Add to share
            xx = app.area.XData;
            yy = app.area.YData;
            level = round(app.Spinner.Value);
            
            [h, ~] = size(app.img);
            hs = h/8;
            for x = min(xx)/8:max(xx)/8
                app.S(level).s = [app.S(level).s ((x*hs)+min(yy)/8):((x*hs)+max(yy)/8)];
            end
            % Redrow with red
            delete(app.area);
            app.area = fill(app.UIAxes, [min(xx);max(xx);max(xx);min(xx)],[min(yy);min(yy);max(yy);max(yy)],'r','facealpha',0.6);
            
            set(app.Spinner,'Enable','on');
            set(app.AddSensitiveAreaButton,'Enable','on');
            set(app.DoShareButton,'Enable','on');
            set(app.OKButton,'Enable','off');
            set(app.CancelButton,'Enable','off');
            app.DebugDataLabel.Text = 'Add sensitive area or Do share';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.area);
            
            set(app.Spinner,'Enable','on');
            set(app.AddSensitiveAreaButton,'Enable','on');
            set(app.DoShareButton,'Enable','on');
            set(app.OKButton,'Enable','off');
            set(app.CancelButton,'Enable','off');
            app.DebugDataLabel.Text = 'Add sensitive area or Do share';
        end

        % Button pushed function: DoShareButton
        function DoShareButtonPushed(app, event)
            set(app.Spinner,'Enable','off');
            set(app.AddSensitiveAreaButton,'Enable','off');
            set(app.DoShareButton,'Enable','off');
            
            %%%
            app.DebugDataLabel.Text = 'Wait 5 sec';
            hold(app.UIAxes, 'off');
            app.img = double(app.img);
            [height, width] = size(app.img);
            
            % Do DCT
            for j = 1:8:width
                for i = 1:8:height
                    app.img(i:i+7,j:j+7) = round( dct2( app.img(i:i+7,j:j+7) )./app.QT );
                end
            end
            app.img(1:8:height,1:8:width) = app.img(1:8:height,1:8:width) - 128;
            app.img = app.img + 125;
            
            % Do truncate
            for j = 1:width
                for i = 1:height
                    if app.img(i,j) > 250
                        app.img(i,j) = 250;
                    elseif app.img(i,j) < 0
                        app.img(i,j) = 0;
                    end
                end
            end
            
            app.img(1:8:height,1:8:width) = app.img(1:8:height,1:8:width) + 128;
            app.img = app.img - 125;
            
            for level = 2:12
                app.outImg(level).img = zeros(height, width);
            end
            
            % Do Simulation
            K = [0 1 2 3 5 10 21 62 64 64 64 64];
            for level = 2:12
                for now = 1:K(level)
                    ii = floor((app.Z(now)-1)/8);
                    jj = mod(app.Z(now)-1,8);
                    app.outImg(level).img(1+ii:8:height,1+jj:8:width) = app.img(1+ii:8:height,1+jj:8:width);
                end
            end
            
            hs = height/8;
            for j = 1:8:width
                for i = 1:8:height
                    for level = 12:-1:2
                        if sum( find( app.S(level).s == (floor((j-1)/8)*hs+ceil(i/8)) ) ) > 0
                            for idx = 2:level-1
                                for jj = 0:7
                                    for ii = 0:7
                                        if app.outImg(idx).img(i+ii,j+jj) > 0
                                            app.outImg(idx).img(i+ii,j+jj) = randi([-125 125]);
                                        end
                                    end
                                end
                                app.outImg(idx).img(i,j) = app.outImg(idx).img(i,j) +128;
                            end
                            break
                        end
                    end
                end
            end
            
            % Do IDCT
            for level = 2:12
                app.DebugDataLabel.Text = ['Wait ' num2str(5-round(level/3)) ' sec'];
                for j = 1:8:width
                    for i = 1:8:height
                        app.outImg(level).img(i:i+7,j:j+7) = idct2( app.outImg(level).img(i:i+7,j:j+7) .* app.QT );
                    end
                end
                app.outImg(level).img = uint8(app.outImg(level).img);
            end
                        
            app.DebugDataLabel.Text = 'Finished, Use scrollbar to see Image of different level';
            %%%
            
            ShareLevelSliderValueChanged(app,event);
            set(app.ShareLevelSlider,'Enable','on');
            set(app.StartCameraButton,'Enable','on');
            set(app.UseSampleImageButton,'Enable','on');
            set(app.ListBox,'Enable','on');
        end

        % Value changed function: ShareLevelSlider
        function ShareLevelSliderValueChanged(app, event)
            value = round(app.ShareLevelSlider.Value);
            app.ShareLevelSlider.Value = round(value);
            imshow(app.outImg(value).img, 'Parent', app.UIAxes);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: UseSampleImageButton
        function UseSampleImageButtonPushed(app, event)
            % Initial Everytime
            set(app.ShareLevelSlider,'Enable','off');
            for i = 2:12
                app.S(i).s = [];
            end
            
            app.img = imread([app.ListBox.Value '.bmp']);
            imshow(app.img, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');

            set(app.StartCameraButton,'Enable','off');
            set(app.UseSampleImageButton,'Enable','off');
            set(app.ListBox,'Enable','off');
            set(app.Spinner,'Enable','on');
            set(app.AddSensitiveAreaButton,'Enable','on');
            set(app.DoShareButton,'Enable','on');
            app.DebugDataLabel.Text = 'Add sensitive area or Do share';
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            close all force
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.TakePhotoButton = uibutton(app.UIFigure, 'push');
            app.TakePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakePhotoButtonPushed, true);
            app.TakePhotoButton.Enable = 'off';
            app.TakePhotoButton.Text = 'Take Photo';
            app.RetakePhotoButton = uibutton(app.UIFigure, 'push');
            app.RetakePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @RetakePhotoButtonPushed, true);
            app.RetakePhotoButton.Enable = 'off';
            app.RetakePhotoButton.Text = 'Retake Photo';
            app.UsePhotoButton = uibutton(app.UIFigure, 'push');
            app.UsePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UsePhotoButtonPushed, true);
            app.UsePhotoButton.Enable = 'off';
            app.UsePhotoButton.Text = 'Use Photo';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.AddSensitiveAreaButton = uibutton(app.UIFigure, 'push');
            app.AddSensitiveAreaButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensitiveAreaButtonPushed, true);
            app.AddSensitiveAreaButton.Enable = 'off';
            app.AddSensitiveAreaButton.Text = 'Add Sensitive Area';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.DoShareButton = uibutton(app.UIFigure, 'push');
            app.DoShareButton.ButtonPushedFcn = createCallbackFcn(app, @DoShareButtonPushed, true);
            app.DoShareButton.Enable = 'off';
            app.DoShareButton.Text = 'Do Share';
            app.ShareLevelSliderLabel = uilabel(app.UIFigure);
            app.ShareLevelSliderLabel.Enable = 'off';
            app.ShareLevelSliderLabel.Text = 'Share Level';
            app.ShareLevelSlider = uislider(app.UIFigure);
            app.ShareLevelSlider.Limits = [2 12];
            app.ShareLevelSlider.MajorTicks = [2 3 4 5 6 7 8 9 10 11 12];
            app.ShareLevelSlider.ValueChangedFcn = createCallbackFcn(app, @ShareLevelSliderValueChanged, true);
            app.ShareLevelSlider.MinorTicks = [];
            app.ShareLevelSlider.Enable = 'off';
            app.ShareLevelSlider.Value = 9;
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [2 12];
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 9;
            app.DebugDataLabel = uilabel(app.UIFigure);
            app.DebugDataLabel.Text = 'Debug Data';
            app.UseSampleImageButton = uibutton(app.UIFigure, 'push');
            app.UseSampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @UseSampleImageButtonPushed, true);
            app.UseSampleImageButton.Text = 'Use Sample Image';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'ID', 'Passport', 'Lena'};
            app.ListBox.Value = 'ID';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end